classdef Results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectanotheremotionButton   matlab.ui.control.Button
        ExploreMoreRecommendationsButton  matlab.ui.control.Button
        HereswhatwefoundforyouLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = public)  
        currSongNum = 0;
        data;
    end
    
    properties (Access = private)
        songLbls =  []% Description
    end


    methods (Access = public)

        function createUI(app, data, emotion) 
            % Emotions: 'Happy', 'Sad', 'Dynamic', 'Relaxed'
            SetBackground(app, emotion);
            
            
            CreateSongResultsUI(app, data, app.currSongNum);
        end

        function SetBackground(app, emotion) 
            calmImgSrc = 'CalmButtonNoText.jpg';
            happyImgSrc = 'HappyButtonNoText.jpg';
            sadImgSrc = 'SadButtonNoText.jpg';
            dynamicImgSrc = 'HypeButtonNoText.jpg';

            if(strcmp(emotion, "Happy"))
                app.Image.ImageSource = happyImgSrc;
            end
            
            if(strcmp(emotion, "Calm"))
                app.Image.ImageSource = calmImgSrc;
            end
            
            if(strcmp(emotion, "Sad"))
                app.Image.ImageSource = sadImgSrc;
            end
            
            if(strcmp(emotion, "Dynamic"))
                app.Image.ImageSource = dynamicImgSrc;
            end
            
        end

        function CreateSongResultsUI(app, data, startNum)

           for(i = 1:5)
                songTitle = data.name(startNum + i);
                artist = data.artist(startNum + i);
                coverImgSrc = data.cover(startNum + i);
                
                songLbl = CreateSongResult(app, songTitle, artist, coverImgSrc, i); 
                disp(songLbl.Text);
                % add to prive array of song labels
            end

            app.currSongNum = app.currSongNum + 5;
       end

       function songLbl = CreateSongResult(app, songTitle, artist, coverImgSrc, num)            
            screenPosition = get(app.UIFigure,'Position');
            screenWidth = screenPosition(3)
            screenHeight = screenPosition(4)

            sections = 6
            paddingHeight = 10 
            paddingWidth  = 20     

            sectionHeight = (screenHeight-(paddingHeight*sections)) / sections 
            sectionWidth  = (screenWidth-paddingWidth*2) / 4
           
            startX = paddingWidth + sectionWidth*2
            startY = sectionHeight*(sections-num)-paddingHeight*(num-1)

            coverPosition = [startX startY sectionHeight sectionHeight]
            titlePosition = [startX+sectionHeight+paddingWidth startY sectionWidth*2 sectionHeight]
                            
            fileUrl = strcat('image',num2str(num),'.png')
            urlwrite(coverImgSrc{1}, fileUrl);
            
            cover = uiimage(app.UIFigure,'ImageSource',fileUrl,'ScaleMethod','fit','Position',coverPosition);
            songLbl = uilabel(app.UIFigure, 'Text', strcat(num2str(num),'. ', songTitle,", ",artist), 'WordWrap', 'on', 'FontSize', 24, 'Position', titlePosition, 'FontColor','White');              
                        
            
            % AUDIO CLIP
            % uihyperlink
            % songClip = uihtml(section);
            % urlwrite(audioSrc, 'testAudio.wav')
            
            % songClip.HTMLSource = '<audio controls style="padding-left:20px"><source src="/testAudio.wav" type="audio/wav"></audio>'
            % audioSrc = 'https://p.scdn.co/mp3-preview/1e7df67cef52cd4578f2a344f4bc875cac394301?cid=28ca2d3814414c08858c196d669a3838'
       end

       %function Create
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, emotion)
            createUI(app, data, emotion);
            app.data = data;
            movegui(app.UIFigure, 'center');
           
        end

        % Button pushed function: ExploreMoreRecommendationsButton
        function ExploreMoreRecommendationsButtonPushed(app, event)
            disp('more recs!!')
            for(i = 1:5) 
                disp(app.songLbls)
                app.songLbls(i).Text = '';
            end
            
            CreateSongResultsUI(app, app.data, app.currSongNum)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'BackG Gradient Design FINAL.jpg';
            app.HereswhatwefoundforyouLabel = uilabel(app.UIFigure);
            app.HereswhatwefoundforyouLabel.Text = 'Here''s what we found for you: ';
            app.ExploreMoreRecommendationsButton = uibutton(app.UIFigure, 'push');
            app.ExploreMoreRecommendationsButton.ButtonPushedFcn = createCallbackFcn(app, @ExploreMoreRecommendationsButtonPushed, true);
            app.ExploreMoreRecommendationsButton.Text = 'Explore More Recommendations';
            app.SelectanotheremotionButton = uibutton(app.UIFigure, 'push');
            app.SelectanotheremotionButton.Text = 'Select another emotion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end